classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        ShowRangesButton               matlab.ui.control.Button
        ViewFaultHistoryButton         matlab.ui.control.Button
        GetStartedButton               matlab.ui.control.Button
        UserGuideButton                matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        DescriptionAreaTextArea        matlab.ui.control.TextArea
        AIBasedFaultDetectionSystemLabel  matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        FaultDetectionTab              matlab.ui.container.Tab
        FaultDescriptionTextArea       matlab.ui.control.TextArea
        FaultDescriptionLabel          matlab.ui.control.Label
        FaultStatusLabel               matlab.ui.control.Label
        DetectFaultButton              matlab.ui.control.Button
        FrequencyEditField             matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        VoltageLabel                   matlab.ui.control.Label
        TemperatureEditField           matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel     matlab.ui.control.Label
        CurrentEditField               matlab.ui.control.NumericEditField
        CurrentAEditFieldLabel         matlab.ui.control.Label
        PowerFactorEditField           matlab.ui.control.NumericEditField
        PowerFactorCosOEditFieldLabel  matlab.ui.control.Label
        VoltageEditField               matlab.ui.control.NumericEditField
        FaultDetectionTabLabel         matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        FaultHistoryTab                matlab.ui.container.Tab
        FaultHistoryTable              matlab.ui.control.Table
        ExportButton                   matlab.ui.control.Button
        ClearHistoryButton             matlab.ui.control.Button
        FaultHistoryLabel              matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
    end


    properties (Access = private)
        net;            % Neural network model
        data;           % Store the dataset
        features;       % Store the features
        featureMin;     % Minimum feature values for normalization
        featureMax;     % Maximum feature values for normalization
        faultType;      % Store the fault types (categorical)
        FaultHistoryData = []; % Store fault history
        snoCounter = 1; % Serial number counter

    end

    properties (Access = public)
        result          % To store the predicted fault type
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % Load the trained neural network model
                modelData = load('FaultDetectionNN.mat');

                % Assign model parameters to app properties
                app.net = modelData.net;
                app.featureMin = modelData.featureMin;
                app.featureMax = modelData.featureMax;
                app.faultType = categorical(modelData.data.FaultType);

                % Ensure FaultType is categorical
                faultTypes = categorical(app.FaultType);

                disp('Neural network model loaded successfully.');
            catch ME
                disp('Error loading neural network model:');
                disp(ME.message);
                app.FaultStatusLabel.Text = "Model Load Error";
            end
        end

        % Button pushed function: UserGuideButton
        function UserGuideButtonPushed(app, event)
            msg = sprintf(['Step 1: Enter voltage,current and other input values.\n' ...
                'Step 2: Click "Detect Fault" to analyze the input.\n' ...
                'Step 3: View status label.\n' ...
                'Step 4: Navigate to "Fault History" for past records.\n\n' ...
                ]);
            uialert(app.UIFigure, msg, 'User Guide');
        end

        % Button pushed function: GetStartedButton
        function GetStartedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FaultDetectionTab;
        end

        % Button pushed function: ViewFaultHistoryButton
        function ViewFaultHistoryButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FaultHistoryTab;
        end

        % Button down function: FaultDetectionTab
        function FaultDetectionTabButtonDown(app, event)

        end

        % Button down function: FaultHistoryTab
        function FaultHistoryTabButtonDown(app, event)


        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Check if the table is empty
            if isempty(app.FaultHistoryTable.Data)
                uialert(app.UIFigure, 'No data to export.', 'Error');
                return;
            end

            % Define column names (Ensure these match the number of columns in Data)
            columnNames = {'S.No', 'Voltage', 'Current', 'PowerFactor', 'Temperature', 'Frequency', 'FaultType'};

            % Convert the data to a table
            faultTable = cell2table(app.FaultHistoryTable.Data, 'VariableNames', columnNames);

            % Ask user where to save the file
            [file, path] = uiputfile('FaultHistory.csv', 'Save CSV File');
            if isequal(file, 0) || isequal(path, 0)
                return; % User canceled
            end

            % Full file path
            fullFileName = fullfile(path, file);

            % Write the table to a CSV file
            writetable(faultTable, fullFileName);

            % Show success message
            uialert(app.UIFigure, 'Export Successful!', 'Success');
        end

        % Button pushed function: ClearHistoryButton
        function ClearHistoryButtonPushed(app, event)
            app.FaultHistoryTable.Data = {}; % Clear table data
            uialert(app.UIFigure, 'Fault history cleared!', 'Success');
        end

        % Size changed function: FaultHistoryTab
        function FaultHistoryTabSizeChanged(app, event)
            position = app.FaultHistoryTab.Position;
            % Adjust UI components if needed
        end

        % Button pushed function: DetectFaultButton
        function DetectFaultButtonPushed(app, event)
            % Get user inputs
            voltage = app.VoltageEditField.Value;
            current = app.CurrentEditField.Value;
            powerfactor = app.PowerFactorEditField.Value;
            Temp = app.TemperatureEditField.Value;
            Freq = app.FrequencyEditField.Value;

            % Prepare input data
            new_sample = [voltage, current, powerfactor, Temp, Freq];

            % Normalize input (Avoid division by zero)
            denominator = app.featureMax - app.featureMin;
            denominator(denominator == 0) = 1; % Prevent division by zero
            new_sample = (new_sample - app.featureMin) ./ denominator;

            % Check if Neural Network is loaded
            if isempty(app.net)
                app.FaultStatusLabel.Text = "Neural Network Not Loaded";
                return;
            end

            % Predict fault status using Neural Network
            try
                predictions = app.net(new_sample(:)); % Ensures column vector format
                disp('Raw Predictions:');
                disp(predictions);

                if isempty(predictions) || any(isnan(predictions)) || ~isnumeric(predictions)
                    error('Invalid predictions received from neural network');
                end
            catch ME
                disp('Error in neural network prediction:');
                disp(ME.message);
                app.FaultStatusLabel.Text = "Prediction Error";
                return;
            end

            % Get predicted label (class with highest probability)
            [maxProb, predictedLabel] = max(predictions);

            % Ensure faultTypes are correctly loaded and categorical
            faultTypes = categories(app.faultType); % Extract categories (fault types)

            % Validate predicted label index
            if predictedLabel < 1 || predictedLabel > numel(faultTypes)
                app.FaultStatusLabel.Text = "Unknown Fault";
                return;
            end

            % Set the fault result based on the predicted label
            predictedResult = faultTypes{predictedLabel};
            app.FaultStatusLabel.Text = string(predictedResult);
            app.FaultStatusLabel.FontSize = 18;
            app.FaultStatusLabel.FontWeight = 'bold';
            drawnow;

            % Save Fault History if Fault Exists
            if ~strcmp(predictedResult, "Normal")
                newRow = {app.snoCounter, voltage, current, powerfactor, Temp, Freq, predictedResult};
                if isempty(app.FaultHistoryTable.Data)
                    app.FaultHistoryTable.Data = newRow;
                else
                    app.FaultHistoryTable.Data = [app.FaultHistoryTable.Data; newRow];
                end
                app.snoCounter = app.snoCounter + 1;
            end

            % === Fault Description Box Update ===
            faultDescriptions = containers.Map( ...
                {'Normal', 'Voltage Fluctuation', 'Power Factor Issue', 'Thermal Overload', 'Overload Fault'}, ...
                { ...
                {
                '✅ Normal', ...
                '- System operating within safe limits.', ...
                '- No faults detected.', ...
                '- Suggested action: No action needed.'
                }, ...
                {
                '⚡ Voltage Fluctuation', ...
                '- Occurs due to unstable or inconsistent power supply.', ...
                '- May damage sensitive equipment like computers or sensors.', ...
                '- Suggested action: Check for loose connections, inspect transformer or install a voltage stabilizer.'
                }, ...
                {
                '⚙️ Power Factor Issue', ...
                '- Indicates inefficient power usage in the system.', ...
                '- Can result in higher electricity bills and energy losses.', ...
                '- Suggested action: Install power factor correction capacitors or consult an energy expert.'
                }, ...
                {
                '🌡️ Thermal Overload', ...
                '- Detected overheating in components.', ...
                '- May cause insulation breakdown or equipment burnout.', ...
                '- Suggested action: Check for ventilation issues, overload, or cooling system failure.'
                }, ...
                {
                '🔌 Overload Fault', ...
                '- Load exceeds rated capacity.', ...
                '- May trip breakers or cause equipment failure.', ...
                '- Suggested action: Reduce load, inspect protective devices, or redistribute power.'
                } ...
                });


            % Show description if fault type found
            if isKey(faultDescriptions, predictedResult)
                app.FaultDescriptionTextArea.Value = faultDescriptions(predictedResult);
            else
                app.FaultDescriptionTextArea.Value = 'No description available for this fault.';
            end


        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;

        end

        % Cell edit callback: FaultHistoryTable
        function FaultHistoryTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: ShowRangesButton
        function ShowRangesButtonPushed(app, event)
            msg = sprintf(['📊 *Normal Operating Ranges*\n\n' ...
                '🔹 Voltage: 210V – 240V\n' ...
                '🔹 Current: 0A – 10A\n' ...
                '🔹 Power Factor: 0.95 – 1.0\n' ...
                '🔹 Temperature: 25°C – 70°C\n' ...
                '🔹 Frequency: 49.5Hz – 50.5Hz\n\n' ...
                '✅ Operating within these ranges is considered *Normal*.']);
            uialert(app.UIFigure, msg, 'Normal Ranges Info', 'Icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image3 = uiimage(app.HomeTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '1.jpeg');
            app.AIBasedFaultDetectionSystemLabel = uilabel(app.HomeTab);
            app.AIBasedFaultDetectionSystemLabel.Text = 'AI-Based Fault Detection System';
            app.DescriptionAreaTextArea = uitextarea(app.HomeTab);
            app.DescriptionAreaTextArea.Value = {'📌 Welcome to the AI Fault Detection System'; '✅ Monitor and detect faults in your electrical equipment with smart AI support.'; '✅ Navigate using the tabs above to access live monitoring, fault history, and more.'; '✅ Click on “User Guide” for step-by-step instructions and feature explanations.'};
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo.jpg');
            app.UserGuideButton = uibutton(app.HomeTab, 'push');
            app.UserGuideButton.ButtonPushedFcn = createCallbackFcn(app, @UserGuideButtonPushed, true);
            app.UserGuideButton.Text = 'User Guide';
            app.GetStartedButton = uibutton(app.HomeTab, 'push');
            app.GetStartedButton.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.GetStartedButton.Text = 'Get Started';
            app.ViewFaultHistoryButton = uibutton(app.HomeTab, 'push');
            app.ViewFaultHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFaultHistoryButtonPushed, true);
            app.ViewFaultHistoryButton.Text = 'View Fault History';
            app.ShowRangesButton = uibutton(app.HomeTab, 'push');
            app.ShowRangesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRangesButtonPushed, true);
            app.ShowRangesButton.Text = 'Show Normal Ranges';
            app.TextArea = uitextarea(app.HomeTab);
            app.TextArea.Value = {'It is recommended to first train the model file (FaultDetection.m). Only proceed to open the app after verifying that the model achieves satisfactory accuracy.'};
            app.FaultDetectionTab = uitab(app.TabGroup);
            app.FaultDetectionTab.Title = 'Fault Detection';
            app.FaultDetectionTab.ButtonDownFcn = createCallbackFcn(app, @FaultDetectionTabButtonDown, true);
            app.Image4 = uiimage(app.FaultDetectionTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '2.jpg');
            app.FaultDetectionTabLabel = uilabel(app.FaultDetectionTab);
            app.FaultDetectionTabLabel.Text = 'Fault Detection Tab';
            app.VoltageEditField = uieditfield(app.FaultDetectionTab, 'numeric');
            app.PowerFactorCosOEditFieldLabel = uilabel(app.FaultDetectionTab);
            app.PowerFactorCosOEditFieldLabel.Text = 'Power Factor( CosO)';
            app.PowerFactorEditField = uieditfield(app.FaultDetectionTab, 'numeric');
            app.CurrentAEditFieldLabel = uilabel(app.FaultDetectionTab);
            app.CurrentAEditFieldLabel.Text = 'Current(A)';
            app.CurrentEditField = uieditfield(app.FaultDetectionTab, 'numeric');
            app.TemperatureCEditFieldLabel = uilabel(app.FaultDetectionTab);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C)';
            app.TemperatureEditField = uieditfield(app.FaultDetectionTab, 'numeric');
            app.VoltageLabel = uilabel(app.FaultDetectionTab);
            app.VoltageLabel.Text = 'Voltage(V)';
            app.FrequencyHzEditFieldLabel = uilabel(app.FaultDetectionTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency(Hz)';
            app.FrequencyEditField = uieditfield(app.FaultDetectionTab, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.DetectFaultButton = uibutton(app.FaultDetectionTab, 'push');
            app.DetectFaultButton.ButtonPushedFcn = createCallbackFcn(app, @DetectFaultButtonPushed, true);
            app.DetectFaultButton.Text = 'Detect Fault';
            app.FaultStatusLabel = uilabel(app.FaultDetectionTab);
            app.FaultStatusLabel.Text = 'Fault Status';
            app.FaultDescriptionLabel = uilabel(app.FaultDetectionTab);
            app.FaultDescriptionLabel.Text = {'Fault '; 'Description'};
            app.FaultDescriptionTextArea = uitextarea(app.FaultDetectionTab);
            app.FaultHistoryTab = uitab(app.TabGroup);
            app.FaultHistoryTab.SizeChangedFcn = createCallbackFcn(app, @FaultHistoryTabSizeChanged, true);
            app.FaultHistoryTab.Title = 'Fault History';
            app.FaultHistoryTab.ButtonDownFcn = createCallbackFcn(app, @FaultHistoryTabButtonDown, true);
            app.Image2 = uiimage(app.FaultHistoryTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '3.jpg');
            app.FaultHistoryLabel = uilabel(app.FaultHistoryTab);
            app.FaultHistoryLabel.Text = 'Fault History';
            app.ClearHistoryButton = uibutton(app.FaultHistoryTab, 'push');
            app.ClearHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearHistoryButtonPushed, true);
            app.ClearHistoryButton.Text = 'Clear History Button';
            app.ExportButton = uibutton(app.FaultHistoryTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export ';
            app.FaultHistoryTable = uitable(app.FaultHistoryTab);
            app.FaultHistoryTable.ColumnName = {'S.No.'; 'Voltage(V)'; 'Current(A)'; 'Power factor'; 'Temp'; 'Freq(Hz)'; 'Result'};
            app.FaultHistoryTable.RowName = {};
            app.FaultHistoryTable.CellEditCallback = createCallbackFcn(app, @FaultHistoryTableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end